classdef main_ISS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        PlotparametersPanel            matlab.ui.container.Panel
        QualitythresholdSlider         matlab.ui.control.Slider
        QualitythresholdSliderLabel    matlab.ui.control.Label
        SpotDiagnosticButton           matlab.ui.control.Button
        SliceNameDropDown              matlab.ui.control.DropDown
        SliceNameDropDownLabel         matlab.ui.control.Label
        DataDirectoryEditField         matlab.ui.control.EditField
        DataDirectoryEditFieldLabel    matlab.ui.control.Label
        BackgroundimageDropDown        matlab.ui.control.DropDown
        BackgroundimageDropDownLabel   matlab.ui.control.Label
        UpdatePlotButton               matlab.ui.control.Button
        Tree                           matlab.ui.container.CheckBoxTree
        GenestoplotNode                matlab.ui.container.TreeNode
        PlotISSButton                  matlab.ui.control.Button
        MarkeTypeDropDown              matlab.ui.control.DropDown
        MarkeTypeDropDownLabel         matlab.ui.control.Label
        MarkerLinethicknessEditField   matlab.ui.control.NumericEditField
        MarkerLinethicknessEditFieldLabel  matlab.ui.control.Label
        MarkerSizeEditField            matlab.ui.control.NumericEditField
        MarkerSizeEditFieldLabel       matlab.ui.control.Label
        GenePanelsListBox              matlab.ui.control.ListBox
        GenePanelsListBoxLabel         matlab.ui.control.Label
        ChangeGenespotsmarkersButton   matlab.ui.control.Button
        VisualizeselectedtilesButton   matlab.ui.control.Button
        SteptoloadListBox              matlab.ui.control.ListBox
        SteptoloadListBoxLabel         matlab.ui.control.Label
        LOADButton                     matlab.ui.control.Button
        TilestoUseListBox              matlab.ui.control.ListBox
        TilestoUseListBoxLabel         matlab.ui.control.Label
        MaininitialparametersPanel     matlab.ui.container.Panel
        RoundsuffixEditField           matlab.ui.control.EditField
        RoundsuffixEditField_2Label    matlab.ui.control.Label
        MetadataDirectoryEditField     matlab.ui.control.EditField
        MetadataDirectoryEditFieldLabel  matlab.ui.control.Label
        IgnoreFirstChannelEditField    matlab.ui.control.NumericEditField
        IgnoreFirstChannelEditFieldLabel  matlab.ui.control.Label
        ParametersthatcanbechangedafterextractandfilterLabel  matlab.ui.control.Label
        RunAllButton_2                 matlab.ui.control.Button
        ExtractandfilterButton_2       matlab.ui.control.Button
        OutputDirectoryEditField       matlab.ui.control.EditField
        OutputDirectoryEditFieldLabel  matlab.ui.control.Label
        nExtraRoundsEditField          matlab.ui.control.NumericEditField
        nExtraRoundsEditFieldLabel     matlab.ui.control.Label
        nRoundsEditField               matlab.ui.control.NumericEditField
        nRoundsEditFieldLabel          matlab.ui.control.Label
        nBPEditField                   matlab.ui.control.NumericEditField
        NumberofcolorchannelsEditField_2Label  matlab.ui.control.Label
        RawFileExtensionEditField      matlab.ui.control.EditField
        RawFileExtensionEditField_3Label  matlab.ui.control.Label
        InitialShiftChannelEditField   matlab.ui.control.NumericEditField
        InitialShiftChannelEditField_2Label  matlab.ui.control.Label
        GcampRoundEditField            matlab.ui.control.NumericEditField
        GcampRoundEditField_2Label     matlab.ui.control.Label
        GcampChannelEditField          matlab.ui.control.NumericEditField
        GcampChannelEditField_2Label   matlab.ui.control.Label
        GadRoundEditField              matlab.ui.control.NumericEditField
        GadRoundEditField_2Label       matlab.ui.control.Label
        GadChannelEditField            matlab.ui.control.NumericEditField
        GadChannelEditField_2Label     matlab.ui.control.Label
        AnchorRoundEditField           matlab.ui.control.NumericEditField
        AnchorRoundEditField_2Label    matlab.ui.control.Label
        SliceprefixEditField           matlab.ui.control.EditField
        SliceprefixEditField_2Label    matlab.ui.control.Label
        InputDirectoryEditField        matlab.ui.control.EditField
        InputDirectoryEditFieldLabel   matlab.ui.control.Label
        AnchorChannelEditField         matlab.ui.control.NumericEditField
        AnchorChannelEditField_2Label  matlab.ui.control.Label
        ChannelstoUseListBox           matlab.ui.control.ListBox
        ChannelstoUseListBoxLabel      matlab.ui.control.Label
        RoundstoUseListBox             matlab.ui.control.ListBox
        RoundstoUseListBoxLabel        matlab.ui.control.Label
        DapiRoundEditField             matlab.ui.control.NumericEditField
        DapiRoundEditFieldLabel        matlab.ui.control.Label
        DapiChannelEditField           matlab.ui.control.NumericEditField
        DapiChannelEditFieldLabel      matlab.ui.control.Label
        MakeDAPIimageButton            matlab.ui.control.Button
        CallspotsButton                matlab.ui.control.Button
        FindspotsButton                matlab.ui.control.Button
        RegistertilesButton            matlab.ui.control.Button
    end

    
    properties (Access = public)
        o % main iss object
        Panel % panel gene lists
        Node % Gene nodes
        Info % diverse params
    end
    
    methods (Access = private)
        
        function L = listItems(app, N, suff)
            L = {}; 
            for i=1:N
                L{i} = [suff,num2str(i)];
            end
        end
        function L = setItems(app, N, suff)
            L = {}; 
            for i=1:length(N)
                L{i} = [suff,num2str(N(i))];
            end
        end
        function o = loadISSfile(app,step)
            OutDir = app.OutputDirectoryEditField.Value;
            SliceNb = app.SliceprefixEditField.Value;
            OutputDirectory = fullfile(OutDir,SliceNb,'\output');
            try
                load(fullfile(OutputDirectory, ['o',step]));
            catch
                error('No call spots output found in this folder')
            end
        end
        
        function plotTiles(app)
            o = app.o;
            BigAnchorFile = fullfile(o.OutputDirectory, 'anchor_image.tif');
            BigAnchorIm = imread(BigAnchorFile);
           
            
            TileList = app.TilestoUseListBox.Value;
            TileList = cellfun(@(x) str2num(x),TileList);
            
             
            o.EmptyTiles(:) = 1 ;
            o.EmptyTiles(TileList) = 0;
             NonemptyTiles = find(~o.EmptyTiles)';
            %Plot stitched anchor image
            SquareX1 = [0, 0, o.TileSz];
            SquareY1 = [o.TileSz, 0, 0];
            SquareX2 = [o.TileSz, o.TileSz, 0];
            SquareY2 = [0, o.TileSz, o.TileSz];
            figure(53277);
            set(gcf,'Position',[990,187,1042,1032])
            imagesc(imadjust(BigAnchorIm-o.TilePixelValueShift));
            hold on
            for t=NonemptyTiles
                MyOrigin = o.TileOrigin(t,:,o.ReferenceRound);
                plot(SquareX1 + MyOrigin(2), SquareY1 + MyOrigin(1),...
                    '--', 'Color', 'w');
                plot(SquareX2 + MyOrigin(2), SquareY2 + MyOrigin(1),...
                    ':', 'Color', 'w');
                text(MyOrigin(2)+max(SquareX1/2), MyOrigin(1)+max(SquareX1/2),...
                    sprintf('Tile %d', t), 'color', 'w','FontSize',18);
            end
            title('Stitched Anchor Image');
            daspect([1 1 1])
            hold off

        end
        
        function app = loadPanelLists(app)
            File = readtable(fullfile(app.MetadataDirectoryEditField.Value,'GeneLists_73g_ctx_OtrAdra_Cholinergic_IEG_final.xlsx'));
            app.Panel.Names = File.Panel;
            app.Panel.Genes = File.Gene;
            
        end

        function app = SubsetCodebook(app)
            
            gg = ismember(app.Panel.Names,app.GenePanelsListBox.Value);
            app.Panel.GeneUsed = app.Panel.Genes(gg);
            
            CodeFile = fullfile(app.MetadataDirectoryEditField.Value,'codebook_73g_ctx_OtrAdra_Cholinergic_IEG_Joana_Chrna7.txt');
            fp = fopen(CodeFile, 'r');
            tmp = textscan(fp, '%s %s', inf);
            gg = ismember(tmp{1},app.Panel.GeneUsed);
            CB = table();
            CB.Gene = tmp{1}(gg);
            CB.Code = tmp{2}(gg);
            app.Panel.CodeFile =  fullfile(app.OutputDirectoryEditField.Value,app.SliceprefixEditField.Value,'codebook_temp.txt');
            writetable(CB,app.Panel.CodeFile,'WriteVariableNames',false,'Delimiter','tab')
        end
        
        function app = creatGeneList(app)
            
            GeneList = app.Panel.GeneUsed;
            for i=1:length(GeneList)
                 app.Node.([GeneList{i},'Node']) = uitreenode(app.GenestoplotNode);
                    app.Node.([GeneList{i},'Node']).Text = GeneList{i};
            end
        end

        function  updateGenes(app)
            app.o.MarkerSize = app.MarkerSizeEditField.Value;
            app.o.PlotLineWidth = app.MarkerLinethicknessEditField.Value;
            app.o.MarkerType = app.MarkeTypeDropDown.Value;
            Gene2P = {};
            checkedNodes = app.Tree.CheckedNodes; % Accessing it from the tree component, not a node
            checkedNodes(strcmp(checkedNodes,'Genes to plot')) = [];
            for i = 1:length(checkedNodes)
                Gene2P{i} = checkedNodes(i).Text;
            end

            QualOk = app.Info.probGood > app.Info.Probthresh;
            app.o.iss_change_plot('OMP',[],Gene2P, QualOk); % show all genes
        end
        
        function issPlot(app)
            if isempty(app.o)
                 app.o = loadISSfile(app,'Call_spots_OMP');
            end
            % load background image
            I=[];
            switch app.BackgroundimageDropDown.Value
                case 'DAPI'
                    I = imadjust(imread(fullfile(app.o.OutputDirectory,'background_image.tif')));
                case 'Anchor'
                    I = imadjust(imread(fullfile(app.o.OutputDirectory,'anchor_image.tif')));
            end
            
            [app.Info.probGood, app.Info.QualOK] = predict_omp_quality(app.o, fullfile(app.MetadataDirectoryEditField.Value,'omp_logistic_model.mat'));
            
            % do the plotting
            Roi = round([1, max(app.o.ompSpotGlobalYX(:,2)), ...
                1, max(app.o.ompSpotGlobalYX(:,1))]);
            app.o.plot(I,Roi,'OMP',app.Info.QualOK);
            daspect([1 1 1])
            updateGenes(app)
        end

        function updateSliceList(app)
            D = dir(app.DataDirectoryEditField.Value);
           Dn = {D(:).name};
            Dn = Dn([D.isdir]);
            Dn = Dn(3:end);
            app.SliceNameDropDown.Items = Dn;

            app.OutputDirectoryEditField.Value = app.DataDirectoryEditField.Value;
        end

        function app = changeDirectories(app)

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, MetaPath)
            if nargin>1 
                app.MetadataDirectoryEditField.Value = MetaPath;
            end
            app.Info.Probthresh = 0.5;
            value = app.nBPEditField.Value;
            L = listItems(app,value, '');
             app.ChannelstoUseListBox.Items = L;
             app.ChannelstoUseListBox.Value = L;
            value = app.nRoundsEditField.Value;
             L = listItems(app,value, '');
             app.RoundstoUseListBox.Items = L;
             app.RoundstoUseListBox.Value = L;
             
             try
                 app = loadPanelLists(app);
                 app.GenePanelsListBox.Items = unique(app.Panel.Names,'stable');
                 app.GenePanelsListBox.Value = app.GenePanelsListBox.Items;
                 app = SubsetCodebook(app);

             catch ME
                 error('No codebook or GenePanel lists found in this path')
             end
             app = creatGeneList(app);
             updateSliceList(app);

        end

        % Button pushed function: ExtractandfilterButton_2
        function ExtractandfilterButton_2Pushed(app, event)
            app = GUI_extractANDfilter(app);
        end

        % Button pushed function: RegistertilesButton
        function RegistertilesButtonPushed(app, event)
            app = GUI_register(app,app.o);
        end

        % Value changed function: nBPEditField
        function nBPEditFieldValueChanged(app, event)
            value = app.nBPEditField.Value;

        end

        % Value changed function: nRoundsEditField
        function nRoundsEditFieldValueChanged(app, event)
            value = app.nRoundsEditField.Value;
            
        end

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            value = app.SteptoloadListBox.Value;
            o = loadISSfile(app,value);
            [nY, nX] = size(o.EmptyTiles);
            nTiles = nY*nX;
            L = listItems(app,nTiles, '');
            app.TilestoUseListBox.Items = L;
            
            NonemptyTiles = find(~o.EmptyTiles)';
            L = setItems(app, NonemptyTiles, '');
            app.TilestoUseListBox.Value = L;
            app.o=o;
            
            app = creatGeneList(app);
        end

        % Button pushed function: FindspotsButton
        function FindspotsButtonPushed(app, event)
            app = GUI_find_spots(app,app.o);
        end

        % Button pushed function: CallspotsButton
        function CallspotsButtonPushed(app, event)
            app = GUI_call_spots(app,app.o);
        end

        % Button pushed function: VisualizeselectedtilesButton
        function VisualizeselectedtilesButtonPushed(app, event)
            plotTiles(app);
        end

        % Button pushed function: RunAllButton_2
        function RunAllButton_2Pushed(app, event)
            GUI_extractANDfilter(app);
            GUI_register(app,app.o);
            GUI_find_spots(app,app.o);
            GUI_call_spots(app,app.o);
        end

        % Value changed function: TilestoUseListBox
        function TilestoUseListBoxValueChanged(app, event)
            TileList = app.TilestoUseListBox.Value;
            TileList = cellfun(@(x) str2num(x),TileList);
            
            app.o.EmptyTiles(:) = 1 ;
            app.o.EmptyTiles(TileList) = 0;
        end

        % Value changed function: RoundstoUseListBox
        function RoundstoUseListBoxValueChanged(app, event)
            value = app.RoundstoUseListBox.Value;
             RoundList = app.RoundstoUseListBox.Value;
            RoundList = cellfun(@(x) str2num(x),RoundList);
            
            app.o.UseRounds = RoundList;
        end

        % Value changed function: ChannelstoUseListBox
        function ChannelstoUseListBoxValueChanged(app, event)
             ChannelList = app.ChannelstoUseListBox.Value;
            ChannelList = cellfun(@(x) str2num(x),ChannelList);
            
            app.o.UseRounds = ChannelList;
            
        end

        % Button pushed function: ChangeGenespotsmarkersButton
        function ChangeGenespotsmarkersButtonPushed(app, event)
            if ~isempty(app.o) & ~isempty(app.o.GeneNames)
                geneList = app.o.GeneNames;
                GenePanelGUI(geneList);
            else
                GenePanelGUI(app.Panel.GeneUsed)
            end
            
        end

        % Value changed function: GenePanelsListBox
        function GenePanelsListBoxValueChanged(app, event)
            app = SubsetCodebook(app);
            app = creatGeneList(app);
        end

        % Value changed function: MetadataDirectoryEditField
        function MetadataDirectoryEditFieldValueChanged(app, event)
            app = loadPanelLists(app);
            app.GenePanelsListBox.Items = unique(app.Panel.Names,'stable');
            app.GenePanelsListBox.Value = app.GenePanelsListBox.Items;
            app = SubsetCodebook(app);
            app = creatGeneList(app);
        end

        % Button pushed function: PlotISSButton
        function PlotISSButtonPushed(app, event)
            issPlot(app)
        end

        % Button pushed function: UpdatePlotButton
        function UpdatePlotButtonPushed(app, event)
            updateGenes(app);
        end

        % Value changed function: DataDirectoryEditField
        function DataDirectoryEditFieldValueChanged(app, event)
             app.o = [];
             updateSliceList(app);
            app.SliceprefixEditField.Value = app.SliceNameDropDown.Value;
        end

        % Value changed function: SliceNameDropDown
        function SliceNameDropDownValueChanged(app, event)
            app.o = [];
            app.SliceprefixEditField.Value = app.SliceNameDropDown.Value;
        end

        % Button pushed function: SpotDiagnosticButton
        function SpotDiagnosticButtonPushed(app, event)
            
        end

        % Value changed function: QualitythresholdSlider
        function QualitythresholdSliderValueChanged(app, event)
           app.Info.Probthresh = event.Value;
            updateGenes(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.RegistertilesButton = uibutton(app.UIFigure, 'push');
            app.RegistertilesButton.ButtonPushedFcn = createCallbackFcn(app, @RegistertilesButtonPushed, true);
            app.RegistertilesButton.Text = 'Register tiles';
            app.FindspotsButton = uibutton(app.UIFigure, 'push');
            app.FindspotsButton.ButtonPushedFcn = createCallbackFcn(app, @FindspotsButtonPushed, true);
            app.FindspotsButton.Text = 'Find spots';
            app.CallspotsButton = uibutton(app.UIFigure, 'push');
            app.CallspotsButton.ButtonPushedFcn = createCallbackFcn(app, @CallspotsButtonPushed, true);
            app.CallspotsButton.Text = 'Call spots';
            app.MakeDAPIimageButton = uibutton(app.UIFigure, 'push');
            app.MakeDAPIimageButton.Text = 'MakeDAPIimage';
            app.DapiChannelEditFieldLabel = uilabel(app.UIFigure);
            app.DapiChannelEditFieldLabel.Text = 'DapiChannel';
            app.DapiChannelEditField = uieditfield(app.UIFigure, 'numeric');
            app.DapiChannelEditField.Value = 1;
            app.DapiRoundEditFieldLabel = uilabel(app.UIFigure);
            app.DapiRoundEditFieldLabel.Text = 'DapiRound';
            app.DapiRoundEditField = uieditfield(app.UIFigure, 'numeric');
            app.DapiRoundEditField.Value = 8;
            app.RoundstoUseListBoxLabel = uilabel(app.UIFigure);
            app.RoundstoUseListBoxLabel.Text = 'Rounds to Use';
            app.RoundstoUseListBox = uilistbox(app.UIFigure);
            app.RoundstoUseListBox.Items = {'Round 1'};
            app.RoundstoUseListBox.Multiselect = 'on';
            app.RoundstoUseListBox.ValueChangedFcn = createCallbackFcn(app, @RoundstoUseListBoxValueChanged, true);
            app.RoundstoUseListBox.Value = {'Round 1'};
            app.ChannelstoUseListBoxLabel = uilabel(app.UIFigure);
            app.ChannelstoUseListBoxLabel.Text = 'Channels to Use';
            app.ChannelstoUseListBox = uilistbox(app.UIFigure);
            app.ChannelstoUseListBox.Items = {'Channel 1'};
            app.ChannelstoUseListBox.Multiselect = 'on';
            app.ChannelstoUseListBox.ValueChangedFcn = createCallbackFcn(app, @ChannelstoUseListBoxValueChanged, true);
            app.ChannelstoUseListBox.Value = {'Channel 1'};
            app.MaininitialparametersPanel = uipanel(app.UIFigure);
            app.MaininitialparametersPanel.Title = 'Main initial parameters';
            app.AnchorChannelEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.AnchorChannelEditField_2Label.Text = 'AnchorChannel';
            app.AnchorChannelEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.AnchorChannelEditField.Value = 6;
            app.InputDirectoryEditFieldLabel = uilabel(app.MaininitialparametersPanel);
            app.InputDirectoryEditFieldLabel.Text = 'InputDirectory';
            app.InputDirectoryEditField = uieditfield(app.MaininitialparametersPanel, 'text');
            app.InputDirectoryEditField.Value = 'F:\Test-ISS-GUI';
            app.SliceprefixEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.SliceprefixEditField_2Label.Text = 'Slice prefix';
            app.SliceprefixEditField = uieditfield(app.MaininitialparametersPanel, 'text');
            app.SliceprefixEditField.Value = 'Region1';
            app.AnchorRoundEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.AnchorRoundEditField_2Label.Text = 'AnchorRound';
            app.AnchorRoundEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.AnchorRoundEditField.Value = 8;
            app.GadChannelEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.GadChannelEditField_2Label.Text = 'GadChannel';
            app.GadChannelEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.GadChannelEditField.Value = 4;
            app.GadRoundEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.GadRoundEditField_2Label.Text = 'GadRound';
            app.GadRoundEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.GadRoundEditField.Value = 8;
            app.GcampChannelEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.GcampChannelEditField_2Label.Text = 'GcampChannel';
            app.GcampChannelEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.GcampChannelEditField.Value = 4;
            app.GcampRoundEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.GcampRoundEditField_2Label.Text = 'GcampRound';
            app.GcampRoundEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.GcampRoundEditField.Value = 8;
            app.InitialShiftChannelEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.InitialShiftChannelEditField_2Label.Text = 'InitialShiftChannel';
            app.InitialShiftChannelEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.InitialShiftChannelEditField.Value = 5;
            app.RawFileExtensionEditField_3Label = uilabel(app.MaininitialparametersPanel);
            app.RawFileExtensionEditField_3Label.Text = 'RawFileExtension';
            app.RawFileExtensionEditField = uieditfield(app.MaininitialparametersPanel, 'text');
            app.RawFileExtensionEditField.Value = '.nd2';
            app.NumberofcolorchannelsEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.NumberofcolorchannelsEditField_2Label.Text = 'Number of color channels';
            app.nBPEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.nBPEditField.ValueChangedFcn = createCallbackFcn(app, @nBPEditFieldValueChanged, true);
            app.nBPEditField.Value = 7;
            app.nRoundsEditFieldLabel = uilabel(app.MaininitialparametersPanel);
            app.nRoundsEditFieldLabel.Text = 'nRounds';
            app.nRoundsEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.nRoundsEditField.ValueChangedFcn = createCallbackFcn(app, @nRoundsEditFieldValueChanged, true);
            app.nRoundsEditField.Value = 7;
            app.nExtraRoundsEditFieldLabel = uilabel(app.MaininitialparametersPanel);
            app.nExtraRoundsEditFieldLabel.Text = 'nExtraRounds';
            app.nExtraRoundsEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.nExtraRoundsEditField.Value = 1;
            app.OutputDirectoryEditFieldLabel = uilabel(app.MaininitialparametersPanel);
            app.OutputDirectoryEditFieldLabel.Text = 'OutputDirectory';
            app.OutputDirectoryEditField = uieditfield(app.MaininitialparametersPanel, 'text');
            app.OutputDirectoryEditField.Value = 'F:\Test-ISS-GUI';
            app.ExtractandfilterButton_2 = uibutton(app.MaininitialparametersPanel, 'push');
            app.ExtractandfilterButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExtractandfilterButton_2Pushed, true);
            app.ExtractandfilterButton_2.Text = 'Extract and filter';
            app.RunAllButton_2 = uibutton(app.MaininitialparametersPanel, 'push');
            app.RunAllButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunAllButton_2Pushed, true);
            app.RunAllButton_2.Text = 'RunAll';
            app.ParametersthatcanbechangedafterextractandfilterLabel = uilabel(app.MaininitialparametersPanel);
            app.ParametersthatcanbechangedafterextractandfilterLabel.Text = 'Parameters that can be changed after extract and filter';
            app.IgnoreFirstChannelEditFieldLabel = uilabel(app.MaininitialparametersPanel);
            app.IgnoreFirstChannelEditFieldLabel.Text = 'IgnoreFirstChannel';
            app.IgnoreFirstChannelEditField = uieditfield(app.MaininitialparametersPanel, 'numeric');
            app.MetadataDirectoryEditFieldLabel = uilabel(app.MaininitialparametersPanel);
            app.MetadataDirectoryEditFieldLabel.Text = 'Metadata Directory';
            app.MetadataDirectoryEditField = uieditfield(app.MaininitialparametersPanel, 'text');
            app.MetadataDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @MetadataDirectoryEditFieldValueChanged, true);
            app.MetadataDirectoryEditField.Value = 'C:\Users\bugeon\Documents\plate Specs';
            app.RoundsuffixEditField_2Label = uilabel(app.MaininitialparametersPanel);
            app.RoundsuffixEditField_2Label.Text = 'Round suffix';
            app.RoundsuffixEditField = uieditfield(app.MaininitialparametersPanel, 'text');
            app.RoundsuffixEditField.Value = '_00,_01,_02,_03,_04,_05,_06,_Anchor';
            app.TilestoUseListBoxLabel = uilabel(app.UIFigure);
            app.TilestoUseListBoxLabel.Text = 'Tiles to Use';
            app.TilestoUseListBox = uilistbox(app.UIFigure);
            app.TilestoUseListBox.Items = {'Tile 1', '', '', ''};
            app.TilestoUseListBox.Multiselect = 'on';
            app.TilestoUseListBox.ValueChangedFcn = createCallbackFcn(app, @TilestoUseListBoxValueChanged, true);
            app.TilestoUseListBox.Value = {'Tile 1'};
            app.LOADButton = uibutton(app.UIFigure, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.SteptoloadListBoxLabel = uilabel(app.UIFigure);
            app.SteptoloadListBoxLabel.Text = 'Step to load';
            app.SteptoloadListBox = uilistbox(app.UIFigure);
            app.SteptoloadListBox.Items = {'Extract', 'Register', 'Find_spots', 'Call_spots_OMP'};
            app.SteptoloadListBox.Value = 'Extract';
            app.VisualizeselectedtilesButton = uibutton(app.UIFigure, 'push');
            app.VisualizeselectedtilesButton.ButtonPushedFcn = createCallbackFcn(app, @VisualizeselectedtilesButtonPushed, true);
            app.VisualizeselectedtilesButton.Text = 'Visualize selected tiles';
            app.ChangeGenespotsmarkersButton = uibutton(app.UIFigure, 'push');
            app.ChangeGenespotsmarkersButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeGenespotsmarkersButtonPushed, true);
            app.ChangeGenespotsmarkersButton.Text = 'Change Gene spots markers';
            app.GenePanelsListBoxLabel = uilabel(app.UIFigure);
            app.GenePanelsListBoxLabel.Text = 'Gene Panels';
            app.GenePanelsListBox = uilistbox(app.UIFigure);
            app.GenePanelsListBox.Items = {'Panel 1'};
            app.GenePanelsListBox.Multiselect = 'on';
            app.GenePanelsListBox.ValueChangedFcn = createCallbackFcn(app, @GenePanelsListBoxValueChanged, true);
            app.GenePanelsListBox.Value = {'Panel 1'};
            app.PlotparametersPanel = uipanel(app.UIFigure);
            app.PlotparametersPanel.Title = 'Plot parameters';
            app.MarkerSizeEditFieldLabel = uilabel(app.PlotparametersPanel);
            app.MarkerSizeEditFieldLabel.Text = 'Marker Size';
            app.MarkerSizeEditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.MarkerSizeEditField.Value = 5;
            app.MarkerLinethicknessEditFieldLabel = uilabel(app.PlotparametersPanel);
            app.MarkerLinethicknessEditFieldLabel.Text = 'Marker Line thickness';
            app.MarkerLinethicknessEditField = uieditfield(app.PlotparametersPanel, 'numeric');
            app.MarkerLinethicknessEditField.Value = 1.2;
            app.MarkeTypeDropDownLabel = uilabel(app.PlotparametersPanel);
            app.MarkeTypeDropDownLabel.Text = 'Marke Type';
            app.MarkeTypeDropDown = uidropdown(app.PlotparametersPanel);
            app.MarkeTypeDropDown.Items = {'GeneSymbols', 'Dots', 'Letters'};
            app.MarkeTypeDropDown.Value = 'GeneSymbols';
            app.PlotISSButton = uibutton(app.PlotparametersPanel, 'push');
            app.PlotISSButton.ButtonPushedFcn = createCallbackFcn(app, @PlotISSButtonPushed, true);
            app.PlotISSButton.Text = 'Plot ISS';
            app.Tree = uitree(app.PlotparametersPanel, 'checkbox');
            app.GenestoplotNode = uitreenode(app.Tree);
            app.GenestoplotNode.Text = 'Genes to plot';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.GenestoplotNode];
            app.UpdatePlotButton = uibutton(app.PlotparametersPanel, 'push');
            app.UpdatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatePlotButtonPushed, true);
            app.UpdatePlotButton.Text = 'Update Plot';
            app.BackgroundimageDropDownLabel = uilabel(app.PlotparametersPanel);
            app.BackgroundimageDropDownLabel.Text = 'Background image';
            app.BackgroundimageDropDown = uidropdown(app.PlotparametersPanel);
            app.BackgroundimageDropDown.Items = {'DAPI', 'Anchor'};
            app.BackgroundimageDropDown.Value = 'DAPI';
            app.DataDirectoryEditFieldLabel = uilabel(app.PlotparametersPanel);
            app.DataDirectoryEditFieldLabel.Text = 'Data Directory';
            app.DataDirectoryEditField = uieditfield(app.PlotparametersPanel, 'text');
            app.DataDirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @DataDirectoryEditFieldValueChanged, true);
            app.DataDirectoryEditField.Value = 'F:\Test-ISS-GUI';
            app.SliceNameDropDownLabel = uilabel(app.PlotparametersPanel);
            app.SliceNameDropDownLabel.Text = 'Slice Name';
            app.SliceNameDropDown = uidropdown(app.PlotparametersPanel);
            app.SliceNameDropDown.Items = {''};
            app.SliceNameDropDown.ValueChangedFcn = createCallbackFcn(app, @SliceNameDropDownValueChanged, true);
            app.SliceNameDropDown.Value = '';
            app.SpotDiagnosticButton = uibutton(app.PlotparametersPanel, 'push');
            app.SpotDiagnosticButton.ButtonPushedFcn = createCallbackFcn(app, @SpotDiagnosticButtonPushed, true);
            app.SpotDiagnosticButton.Text = 'Spot Diagnostic';
            app.QualitythresholdSliderLabel = uilabel(app.PlotparametersPanel);
            app.QualitythresholdSliderLabel.Text = 'Quality threshold';
            app.QualitythresholdSlider = uislider(app.PlotparametersPanel);
            app.QualitythresholdSlider.Limits = [0 1];
            app.QualitythresholdSlider.ValueChangedFcn = createCallbackFcn(app, @QualitythresholdSliderValueChanged, true);
            app.QualitythresholdSlider.Value = 0.5;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_ISS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end